classdef npd_5b_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        StepSwitch         matlab.ui.control.Switch
        StepLabel          matlab.ui.control.Label
        SeriesLength       matlab.ui.control.Spinner
        SeriesLengthLabel  matlab.ui.control.Label
        Resolution         matlab.ui.control.Spinner
        ResolutionLabel    matlab.ui.control.Label
        FrequencyUnits     matlab.ui.control.Label
        sLabel             matlab.ui.control.Label
        domain             matlab.ui.control.Spinner
        frequencyValue     matlab.ui.control.Spinner
        amplitudeValue     matlab.ui.control.Spinner
        DomainLabel        matlab.ui.control.Label
        Knob               matlab.ui.control.DiscreteKnob
        AmplitudeLabel     matlab.ui.control.Label
        WaveformTypes      matlab.ui.container.ButtonGroup
        DataFileButton     matlab.ui.control.RadioButton
        ExponentialButton  matlab.ui.control.RadioButton
        SawtoothButton     matlab.ui.control.RadioButton
        SquareButton       matlab.ui.control.RadioButton
        ParabolaButton     matlab.ui.control.RadioButton
        Angle_Graph        matlab.ui.control.UIAxes
        TFS_Graph          matlab.ui.control.UIAxes
        Magnitude_Graph    matlab.ui.control.UIAxes
        Modes_Graph        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = DrawGraphs(app)
            % get parameters from user
            Domain = [0, app.domain.Value];
            Range = [0, 0];
            A = app.amplitudeValue.Value;
            resolution = app.Resolution.Value;
            t = 0:resolution:Domain(2);
            angF = 0;
            period = 0;

            switch app.Knob.Value               % lets the user pick how they want to input frequency
                case "Frequency"
                    angF = 2*pi*app.frequencyValue.Value;
                    period = 1/app.frequencyValue.Value;
                case "Angular Frequency"
                    angF = app.frequencyValue.Value;
                    period = 2*pi/app.frequencyValue.Value;
                case "Period"
                    angF = 2*pi/app.frequencyValue.Value;
                    period = app.frequencyValue.Value;
            end

            % select function
            f = [];
            switch app.WaveformTypes.SelectedObject
                case app.ParabolaButton
                f = A * sawtooth(angF*t) .^ 2;
                Range = [0, A*1.1];

                case app.SquareButton
                f = A * square(angF*t);
                Range = [-A*1.1, A*1.1];

                case app.SawtoothButton
                f = A * sawtooth(angF*t);
                Range = [-A*1.1, A*1.1];

                case app.ExponentialButton
                f = A*exp(0.5 .* (1 - sawtooth(angF*t, 0.5) )) ./ exp(1);
                Range = [0.9*A./ exp(1), A*1.1];

                case app.DataFileButton
                file = importdata(uigetfile('*.*','Select a File'));
                t = file(:,1);
                f = file(:,2);
                Domain(2) = t(end);
                resolution = t(2);
                Range = [min(f)*1.1,max(f)*1.1];
                [~, p1] = max(f);
                [~, p2] = max(f(p1+1:end));
                p2 = p2 + p1;
                period = t(p2) - t(p1);
                angF = 2*pi/period;
            end

            % Calculate scalers
            seriesLength = app.SeriesLength.Value;
            a = zeros(1,seriesLength);
            b = zeros(1,seriesLength);
            a_0 = trapz(t(1:period/resolution),f(1:period/resolution)) / period;
            for n = 1:seriesLength
                a(n) = 2.* trapz(t(1:period/resolution),f(1:period/resolution) .* cos(n*angF*t(1:period/resolution))) ./ period;
                b(n) = 2.* trapz(t(1:period/resolution),f(1:period/resolution) .* sin(n*angF*t(1:period/resolution))) ./ period;
            end

            %c_0 = a_0;
            %c = sqrt(a.^2 + b.^2);
            %theta = atan2(-b, a);

            c = [a_0, sqrt(a.^2 + b.^2)];
            theta = [0, atan2(-b, a)];


            % graph 
            cla(app.Modes_Graph) %.UIAxes.cla;
            app.Modes_Graph.Title.String = 'Modes  (\omega_0 = ' + string(angF) + ')';

            ylim(app.TFS_Graph, Range);
            xlim(app.TFS_Graph, Domain);

            %plot(app.Modes_Graph, c_0);
            hold(app.Modes_Graph)
            xlim(app.Modes_Graph, Domain);

            xlim(app.Magnitude_Graph, [0,seriesLength]);
            xlim(app.Angle_Graph, [0,seriesLength]);

            t = 0:resolution:(Domain(2)*4);
            f_tfs = zeros(1, length(t)) ;% + c_0;
            for n = 1:seriesLength+1
                mode = c(n) .* cos((n-1).*angF.*t + theta(n));
                f_tfs = f_tfs + mode;

                plot(app.TFS_Graph, t, f_tfs);
                plot(app.Modes_Graph, t, mode);
                stem(app.Magnitude_Graph, 0:n-1, c(1:n));
                stem(app.Angle_Graph, 0:n-1, theta(1:n));

                if app.StepSwitch.Value == "On"             %lets user choose if they want to watch the f_tfs be built
                    pause(1);
                end
            end
            hold(app.Modes_Graph);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
            switch value
                case "Frequency"
                    app.FrequencyUnits.Text = "1/s";
                case "Angular Frequency"
                    app.FrequencyUnits.Text = "rad/s";
                case "Period"
                    app.FrequencyUnits.Text = "s";
                otherwise
                    app.FrequencyUnits.Text = "";
            end

        end

        % Selection changed function: WaveformTypes
        function WaveformTypesSelectionChanged(app, event)
            DrawGraphs(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Modes_Graph = uiaxes(app.UIFigure);
            title(app.Modes_Graph, 'Modes')
            xlabel(app.Modes_Graph, 't')
            ylabel(app.Modes_Graph, 'y')
            zlabel(app.Modes_Graph, 'Z')
            app.Magnitude_Graph = uiaxes(app.UIFigure);
            title(app.Magnitude_Graph, 'Magnitude')
            xlabel(app.Magnitude_Graph, 'n')
            ylabel(app.Magnitude_Graph, 'C_0')
            zlabel(app.Magnitude_Graph, 'Z')
            app.TFS_Graph = uiaxes(app.UIFigure);
            title(app.TFS_Graph, 'F_{TFS}')
            xlabel(app.TFS_Graph, 't')
            ylabel(app.TFS_Graph, 'Y')
            zlabel(app.TFS_Graph, 'Z')
            app.Angle_Graph = uiaxes(app.UIFigure);
            title(app.Angle_Graph, 'Phase')
            xlabel(app.Angle_Graph, 'n')
            ylabel(app.Angle_Graph, '\theta_n')
            zlabel(app.Angle_Graph, 'Z')
            app.WaveformTypes = uibuttongroup(app.UIFigure);
            app.WaveformTypes.SelectionChangedFcn = createCallbackFcn(app, @WaveformTypesSelectionChanged, true);
            app.WaveformTypes.Title = 'Waveforms ';
            app.ParabolaButton = uiradiobutton(app.WaveformTypes);
            app.ParabolaButton.Text = 'Parabola';
            app.ParabolaButton.Value = true;
            app.SquareButton = uiradiobutton(app.WaveformTypes);
            app.SquareButton.Text = 'Square';
            app.SawtoothButton = uiradiobutton(app.WaveformTypes);
            app.SawtoothButton.Text = 'Sawtooth';
            app.ExponentialButton = uiradiobutton(app.WaveformTypes);
            app.ExponentialButton.Text = 'Exponential ';
            app.DataFileButton = uiradiobutton(app.WaveformTypes);
            app.DataFileButton.Text = 'Data File';
            app.AmplitudeLabel = uilabel(app.UIFigure);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'Frequency', 'Angular Frequency', 'Period'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'Period';
            app.DomainLabel = uilabel(app.UIFigure);
            app.DomainLabel.Text = 'Domain';
            app.amplitudeValue = uispinner(app.UIFigure);
            app.amplitudeValue.Value = 2;
            app.frequencyValue = uispinner(app.UIFigure);
            app.frequencyValue.Value = 2;
            app.domain = uispinner(app.UIFigure);
            app.domain.Value = 10;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.FrequencyUnits = uilabel(app.UIFigure);
            app.FrequencyUnits.Text = '1/s';
            app.ResolutionLabel = uilabel(app.UIFigure);
            app.ResolutionLabel.Text = 'Resolution';
            app.Resolution = uispinner(app.UIFigure);
            app.Resolution.Step = 0.01;
            app.Resolution.Value = 0.01;
            app.SeriesLengthLabel = uilabel(app.UIFigure);
            app.SeriesLengthLabel.Text = 'Series Length';
            app.SeriesLength = uispinner(app.UIFigure);
            app.SeriesLength.Value = 5;
            app.StepLabel = uilabel(app.UIFigure);
            app.StepLabel.Text = 'Step';
            app.StepSwitch = uiswitch(app.UIFigure, 'slider');
            app.StepSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = npd_5b_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end